classdef keypairgenerator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        RSAKeyPairGeneratorReceiverLabel  matlab.ui.control.Label
        ModulusValueLabel               matlab.ui.control.Label
        PublicKeyLabel                  matlab.ui.control.Label
        PrivateKeyLabel                 matlab.ui.control.Label
        modulus_value                   matlab.ui.control.NumericEditField
        publickey_value                 matlab.ui.control.NumericEditField
        privatekey_value                matlab.ui.control.NumericEditField
        GenerateButton                  matlab.ui.control.Button
        privatekey_value_2              matlab.ui.control.NumericEditField
        publickey_value_2               matlab.ui.control.NumericEditField
        modulus_value_2                 matlab.ui.control.NumericEditField
        PrivateKeyLabel_2               matlab.ui.control.Label
        PublicKeyLabel_2                matlab.ui.control.Label
        ModulusValueLabel_2             matlab.ui.control.Label
        RSAKeyPairGeneratorSenderLabel  matlab.ui.control.Label
        HybridCryptographySteganographyLabel  matlab.ui.control.Label
        RandomValueXGeneratorLabel      matlab.ui.control.Label
        ValueofXLabel                   matlab.ui.control.Label
        ValueYGeneratorLabel            matlab.ui.control.Label
        ValueofYLabel                   matlab.ui.control.Label
        GenerateButton_2                matlab.ui.control.Button
        GenerateButton_3                matlab.ui.control.Button
        randomvalue_x                   matlab.ui.control.NumericEditField
        generated_Y                     matlab.ui.control.NumericEditField
        MessageLabel                    matlab.ui.control.Label
        CBCMessageLabel                 matlab.ui.control.Label
        CBC_Message                     matlab.ui.control.EditField
        GenerateButton_4                matlab.ui.control.Button
        EncryptedMessageLabel           matlab.ui.control.Label
        Encrypted_Message               matlab.ui.control.EditField
        MessageEncryptionLabel          matlab.ui.control.Label
        DigestofEncryptedMessageLabel   matlab.ui.control.Label
        Digest_Encrypted_Message        matlab.ui.control.EditField
        GenerateButton_5                matlab.ui.control.Button
        DigitalSignatureLabel           matlab.ui.control.Label
        GenerateButton_6                matlab.ui.control.Button
        Digital_Signature               matlab.ui.control.EditField
        CreationofStegoImageLabel       matlab.ui.control.Label
        SelectCoverImageButton          matlab.ui.control.Button
        Cover_Image                     matlab.ui.control.EditField
        init_vector                     matlab.ui.control.EditField
        CreateStegoImageButton          matlab.ui.control.Button
        is_stego                        matlab.ui.control.EditField
        ShowCoverImageButton            matlab.ui.control.Button
        ShowStegoImageButton            matlab.ui.control.Button
        CalculatePSNRindBButton         matlab.ui.control.Button
        psnr_value                      matlab.ui.control.EditField
        Message_Text                    matlab.ui.control.EditField
        confidentiality                 matlab.ui.control.EditField
        TextCheckBox                    matlab.ui.control.CheckBox
        ImageCheckBox                   matlab.ui.control.CheckBox
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GenerateButton
        function GenerateButtonPushed(app, event)
            warning('off','all');
            [a, b, c] = RSA_GenerateKeyPair;
            app.modulus_value.Value = double(a);
            app.publickey_value.Value = double(b);
            app.privatekey_value.Value = double(c);
            [a1, b1, c1] = RSA_GenerateKeyPair;
            app.modulus_value_2.Value = double(a1);
            app.publickey_value_2.Value = double(b1);
            app.privatekey_value_2.Value = double(c1);
        end

        % Button pushed function: GenerateButton_2
        function GenerateButton_2Pushed(app, event)
            warning('off','all');
            app.randomvalue_x.Value = randi(app.publickey_value.Value);
        end

        % Button pushed function: GenerateButton_3
        function GenerateButton_3Pushed(app, event)
            warning('off','all');
            Message_demo1 = int32(app.randomvalue_x.Value);
            Message = Message_demo1 + 48;
            gen_Y = RSA_Encrypt(int32(app.modulus_value.Value), int32(app.publickey_value.Value), Message);
            app.generated_Y.Value = double(gen_Y);
        end

        % Button pushed function: GenerateButton_4
        function GenerateButton_4Pushed(app, event)
            if app.TextCheckBox.Value == 1
                warning('off','all');
                fid = fopen(app.Message_Text.Value,'r');
                A = fscanf(fid,'%c');
                plain_demo = char(A);
                len_plain_demo = length(char(plain_demo));
                
                for i = 1:16
                    init_vector(i) = randi(255);
                end
                pad_value = 16 - mod(len_plain_demo,16);
                new_plain_demo = pad(plain_demo,(len_plain_demo+pad_value));
                new_plain_demo = char(new_plain_demo);
                iterate = length(new_plain_demo)/16;
                plain_ = [];
                plain = [];
                
                b = 1;
                for j = 1:iterate
                    sub_plane{j} = char(new_plain_demo((b):(b+(16-1))));
                    b = b + 16;
                end
                
                plain_{1} = bitxor(int32(sub_plane{1}),int32(init_vector));
                plain{1} = aes_simple_encryption(char(plain_{1}),char(string(app.randomvalue_x.Value)));
                if iterate >= 2
                    for j = 2:iterate
                        plain_{j} = bitxor(int32(sub_plane{j}),int32(plain{j-1}));
                        plain{j} = aes_simple_encryption(char(plain_{j}),char(string(app.randomvalue_x.Value)));
                    end
                end
                plain = strcat(plain{1:iterate});
                plain = char(plain);
                app.CBC_Message.Value = plain;
                ciphertext = aes_simple_encryption(plain,char(string(app.randomvalue_x.Value)));
                app.Encrypted_Message.Value = char(ciphertext);
                app.init_vector.Value = char(init_vector);
                app.confidentiality.Value = 'Confidentiality Ensured!';
            end
            if app.ImageCheckBox.Value == 1
                warning('off','all');
                input = imread(app.Message_Text.Value);
                pixel = size(input);
                pixel = pixel(1);
                crypted = imread(app.Message_Text.Value);
                b = 1;
                subimage = {1:pixel};
                init_vector = [];
                for j = 1:pixel
                    init_vector(j) = randi(255);
                end
                init_vector = int32(init_vector);
                app.init_vector.Value = char(init_vector);
                for j = 1:pixel
                    subimage{j} = char(input((b):(b+(pixel-1))));
                    b = b + pixel;
                end
                key = char(app.randomvalue_x.Value);
                encrypt = {1:pixel};
                row = {1:pixel};
                row{1} = bitxor(int32(subimage{1}),init_vector);
                %disp(row{1});
                encrypt{1} = aes_simple_encryption(row{1},key);
                crypted(1:pixel) = double(encrypt{1});
                enc = [];
                k = pixel+1;
                enc(1:pixel) = int32(encrypt{1});
                for j = 2:pixel
                    row{j} = bitxor(int32(subimage{j}),int32(encrypt{j-1}));
                    encrypt{j} = aes_simple_encryption(row{j},key);
                    crypted((k):(k+(pixel-1))) = double(encrypt{j});
                    enc((k):(k+(pixel-1))) = int32(encrypt{j});
                    k = k + pixel;
                end
                imwrite(crypted,'encrypted.bmp');
                app.CBC_Message.Value = char(enc);
                app.Encrypted_Message.Value = 'Encrypted Image Created';
                app.confidentiality.Value = 'Confidentiality Ensured';
            end
        end

        % Button pushed function: GenerateButton_5
        function GenerateButton_5Pushed(app, event)
            warning('off','all');
            digest = hash(char(app.CBC_Message.Value),'SHA-512');
            app.Digest_Encrypted_Message.Value = digest;
        end

        % Button pushed function: GenerateButton_6
        function GenerateButton_6Pushed(app, event)
            warning('off','all');
            signature_demo = int32(app.Digest_Encrypted_Message.Value);
            digital_signature = RSA_Encrypt(int32(app.modulus_value_2.Value), int32(app.privatekey_value_2.Value), signature_demo);
            app.Digital_Signature.Value = char(digital_signature);
        end

        % Button pushed function: SelectCoverImageButton
        function SelectCoverImageButtonPushed(app, event)
            if app.TextCheckBox.Value == 1
                warning('off','all');
                fid = fopen('PVD_Embed.txt','w');
                fprintf(fid,'%d\n',int32(app.Digital_Signature.Value));
                fclose(fid);
                fid = fopen('PVD_Embed.txt','a+');
                fprintf(fid,'%d\n',int32(app.generated_Y.Value));
                fclose(fid);
                fid = fopen('PVD_Embed.txt','a+');
                fun2 = fprintf(fid,'%d\n',app.modulus_value.Value);
                fclose(fid);
                fid = fopen('PVD_Embed.txt','a+');
                fun2 = fprintf(fid,'%d\n',app.publickey_value.Value);
                fclose(fid);
                fid = fopen('PVD_Embed.txt','a+');
                fun2 = fprintf(fid,'%d\n',app.privatekey_value.Value);
                fclose(fid);
                fid = fopen('PVD_Embed.txt','a+');
                fun2 = fprintf(fid,'%d\n',app.modulus_value_2.Value);
                fclose(fid);
                fid = fopen('PVD_Embed.txt','a+');
                fun2 = fprintf(fid,'%d\n',app.publickey_value_2.Value);
                fclose(fid);
                fid = fopen('PVD_Embed.txt','a+');
                fun2 = fprintf(fid,'%d\n',app.privatekey_value_2.Value);
                fclose(fid);
                fid = fopen('PVD_Embed.txt','a+');
                fun2 = fprintf(fid,'%d\n',length(app.Encrypted_Message.Value));
                fclose(fid);
                warning('off','all');
                fid = fopen(app.Message_Text.Value,'r');
                A = fscanf(fid,'%c');
                %plain_demo = char(A);
                len_plain_demo = length(A);
                fid = fopen('PVD_Embed.txt','a+');
                fun2 = fprintf(fid,'%d\n',len_plain_demo);
                fclose(fid);
                fid = fopen('PVD_Embed.txt','a+');
                fprintf(fid,'%d\n',int32(app.init_vector.Value));
                fclose(fid);
                fid = fopen('PVD_Embed.txt','a+');
                fprintf(fid,'%d\n',int32(app.Encrypted_Message.Value));
                fclose(fid);
                fileu = uigetfile('*.bmp','Select the Cover Image!');
                app.Cover_Image.Value = char(fileu);
            end
            if app.ImageCheckBox.Value == 1
                warning('off','all');
                fid = fopen('PVD_Embed.txt','w');
                fprintf(fid,'%d\n',int32(app.Digital_Signature.Value));
                fclose(fid);
                fid = fopen('PVD_Embed.txt','a+');
                fprintf(fid,'%d\n',int32(app.generated_Y.Value));
                fclose(fid);
                fid = fopen('PVD_Embed.txt','a+');
                fun2 = fprintf(fid,'%d\n',app.modulus_value.Value);
                fclose(fid);
                fid = fopen('PVD_Embed.txt','a+');
                fun2 = fprintf(fid,'%d\n',app.publickey_value.Value);
                fclose(fid);
                fid = fopen('PVD_Embed.txt','a+');
                fun2 = fprintf(fid,'%d\n',app.privatekey_value.Value);
                fclose(fid);
                fid = fopen('PVD_Embed.txt','a+');
                fun2 = fprintf(fid,'%d\n',app.modulus_value_2.Value);
                fclose(fid);
                fid = fopen('PVD_Embed.txt','a+');
                fun2 = fprintf(fid,'%d\n',app.publickey_value_2.Value);
                fclose(fid);
                fid = fopen('PVD_Embed.txt','a+');
                fun2 = fprintf(fid,'%d\n',app.privatekey_value_2.Value);
                fclose(fid);
                input = imread(app.Message_Text.Value);
                pixel = size(input);
                pixel = pixel(1);
                fid = fopen('PVD_Embed.txt','a+');
                fun2 = fprintf(fid,'%d\n',pixel);
                fclose(fid);
                fid = fopen('PVD_Embed.txt','a+');
                fprintf(fid,'%d\n',int32(app.init_vector.Value));
                fclose(fid);
                fid = fopen('PVD_Embed.txt','a+');
                fun2 = fprintf(fid,'%d\n',int32(app.CBC_Message.Value));
                fclose(fid);
                fileu = uigetfile;
                app.Cover_Image.Value = char(fileu);
            end
        end

        % Button pushed function: CreateStegoImageButton
        function CreateStegoImageButtonPushed(app, event)
            warning('off','all');
            PVD_Embed(char(app.Cover_Image.Value));
            app.is_stego.Value = 'Stego Image Created';
        end

        % Button pushed function: ShowCoverImageButton
        function ShowCoverImageButtonPushed(app, event)
            warning('off','all');
            imshow(app.Cover_Image.Value);
        end

        % Button pushed function: ShowStegoImageButton
        function ShowStegoImageButtonPushed(app, event)
            warning('off','all');
            imshow('PVD_stego_image.bmp');
        end

        % Button pushed function: CalculatePSNRindBButton
        function CalculatePSNRindBButtonPushed(app, event)
            warning('off','all');
            A1 = imread(app.Cover_Image.Value);
            B1 = imread("PVD_stego_image.bmp");
            peaksnr = psnr(B1,A1);
            app.psnr_value.Value = num2str(peaksnr);
        end

        % Value changed function: TextCheckBox
        function TextCheckBoxValueChanged(app, event)
            value = app.TextCheckBox.Value;
            if value == 1
                [file,path] = uigetfile('*.txt','Select the text file!');
                app.Message_Text.Value = file;
            end
        end

        % Value changed function: ImageCheckBox
        function ImageCheckBoxValueChanged(app, event)
            value = app.ImageCheckBox.Value;
            if value == 1
                [file,path] = uigetfile('*.bmp','Select the image file!');
                app.Message_Text.Value = file;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.RSAKeyPairGeneratorReceiverLabel = uilabel(app.UIFigure);
            app.RSAKeyPairGeneratorReceiverLabel.Text = 'RSA KeyPair Generator(Receiver)';
            app.ModulusValueLabel = uilabel(app.UIFigure);
            app.ModulusValueLabel.Text = 'Modulus Value';
            app.PublicKeyLabel = uilabel(app.UIFigure);
            app.PublicKeyLabel.Text = 'Public Key';
            app.PrivateKeyLabel = uilabel(app.UIFigure);
            app.PrivateKeyLabel.Text = 'Private Key';
            app.modulus_value = uieditfield(app.UIFigure, 'numeric');
            app.modulus_value.ValueDisplayFormat = '%5d';
            app.publickey_value = uieditfield(app.UIFigure, 'numeric');
            app.publickey_value.ValueDisplayFormat = '%5d';
            app.privatekey_value = uieditfield(app.UIFigure, 'numeric');
            app.privatekey_value.ValueDisplayFormat = '%5d';
            app.GenerateButton = uibutton(app.UIFigure, 'push');
            app.GenerateButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateButtonPushed, true);
            app.GenerateButton.Text = 'Generate';
            app.privatekey_value_2 = uieditfield(app.UIFigure, 'numeric');
            app.privatekey_value_2.ValueDisplayFormat = '%5d';
            app.publickey_value_2 = uieditfield(app.UIFigure, 'numeric');
            app.publickey_value_2.ValueDisplayFormat = '%5d';
            app.modulus_value_2 = uieditfield(app.UIFigure, 'numeric');
            app.modulus_value_2.ValueDisplayFormat = '%5d';
            app.PrivateKeyLabel_2 = uilabel(app.UIFigure);
            app.PrivateKeyLabel_2.Text = 'Private Key';
            app.PublicKeyLabel_2 = uilabel(app.UIFigure);
            app.PublicKeyLabel_2.Text = 'Public Key';
            app.ModulusValueLabel_2 = uilabel(app.UIFigure);
            app.ModulusValueLabel_2.Text = 'Modulus Value';
            app.RSAKeyPairGeneratorSenderLabel = uilabel(app.UIFigure);
            app.RSAKeyPairGeneratorSenderLabel.Text = 'RSA KeyPair Generator(Sender)';
            app.HybridCryptographySteganographyLabel = uilabel(app.UIFigure);
            app.HybridCryptographySteganographyLabel.Text = 'Hybrid Cryptography & Steganography';
            app.RandomValueXGeneratorLabel = uilabel(app.UIFigure);
            app.RandomValueXGeneratorLabel.Text = 'Random Value X Generator';
            app.ValueofXLabel = uilabel(app.UIFigure);
            app.ValueofXLabel.Text = 'Value of X';
            app.ValueYGeneratorLabel = uilabel(app.UIFigure);
            app.ValueYGeneratorLabel.Text = '   Value Y Generator';
            app.ValueofYLabel = uilabel(app.UIFigure);
            app.ValueofYLabel.Text = 'Value of Y';
            app.GenerateButton_2 = uibutton(app.UIFigure, 'push');
            app.GenerateButton_2.ButtonPushedFcn = createCallbackFcn(app, @GenerateButton_2Pushed, true);
            app.GenerateButton_2.Text = 'Generate';
            app.GenerateButton_3 = uibutton(app.UIFigure, 'push');
            app.GenerateButton_3.ButtonPushedFcn = createCallbackFcn(app, @GenerateButton_3Pushed, true);
            app.GenerateButton_3.Text = 'Generate';
            app.randomvalue_x = uieditfield(app.UIFigure, 'numeric');
            app.randomvalue_x.ValueDisplayFormat = '%5d';
            app.generated_Y = uieditfield(app.UIFigure, 'numeric');
            app.generated_Y.ValueDisplayFormat = '%5d';
            app.MessageLabel = uilabel(app.UIFigure);
            app.MessageLabel.Text = 'Message';
            app.CBCMessageLabel = uilabel(app.UIFigure);
            app.CBCMessageLabel.Text = 'CBC Message';
            app.CBC_Message = uieditfield(app.UIFigure, 'text');
            app.GenerateButton_4 = uibutton(app.UIFigure, 'push');
            app.GenerateButton_4.ButtonPushedFcn = createCallbackFcn(app, @GenerateButton_4Pushed, true);
            app.GenerateButton_4.Text = 'Generate';
            app.EncryptedMessageLabel = uilabel(app.UIFigure);
            app.EncryptedMessageLabel.Text = 'Encrypted Message';
            app.Encrypted_Message = uieditfield(app.UIFigure, 'text');
            app.MessageEncryptionLabel = uilabel(app.UIFigure);
            app.MessageEncryptionLabel.Text = 'Message Encryption';
            app.DigestofEncryptedMessageLabel = uilabel(app.UIFigure);
            app.DigestofEncryptedMessageLabel.Text = 'Digest of Encrypted Message';
            app.Digest_Encrypted_Message = uieditfield(app.UIFigure, 'text');
            app.GenerateButton_5 = uibutton(app.UIFigure, 'push');
            app.GenerateButton_5.ButtonPushedFcn = createCallbackFcn(app, @GenerateButton_5Pushed, true);
            app.GenerateButton_5.Text = 'Generate';
            app.DigitalSignatureLabel = uilabel(app.UIFigure);
            app.DigitalSignatureLabel.Text = 'Digital Signature';
            app.GenerateButton_6 = uibutton(app.UIFigure, 'push');
            app.GenerateButton_6.ButtonPushedFcn = createCallbackFcn(app, @GenerateButton_6Pushed, true);
            app.GenerateButton_6.Text = 'Generate';
            app.Digital_Signature = uieditfield(app.UIFigure, 'text');
            app.CreationofStegoImageLabel = uilabel(app.UIFigure);
            app.CreationofStegoImageLabel.Text = 'Creation of Stego-Image';
            app.SelectCoverImageButton = uibutton(app.UIFigure, 'push');
            app.SelectCoverImageButton.ButtonPushedFcn = createCallbackFcn(app, @SelectCoverImageButtonPushed, true);
            app.SelectCoverImageButton.Text = 'Select Cover Image';
            app.Cover_Image = uieditfield(app.UIFigure, 'text');
            app.init_vector = uieditfield(app.UIFigure, 'text');
            app.CreateStegoImageButton = uibutton(app.UIFigure, 'push');
            app.CreateStegoImageButton.ButtonPushedFcn = createCallbackFcn(app, @CreateStegoImageButtonPushed, true);
            app.CreateStegoImageButton.Text = 'Create Stego Image';
            app.is_stego = uieditfield(app.UIFigure, 'text');
            app.ShowCoverImageButton = uibutton(app.UIFigure, 'push');
            app.ShowCoverImageButton.ButtonPushedFcn = createCallbackFcn(app, @ShowCoverImageButtonPushed, true);
            app.ShowCoverImageButton.Text = 'Show Cover Image';
            app.ShowStegoImageButton = uibutton(app.UIFigure, 'push');
            app.ShowStegoImageButton.ButtonPushedFcn = createCallbackFcn(app, @ShowStegoImageButtonPushed, true);
            app.ShowStegoImageButton.Text = 'Show Stego Image';
            app.CalculatePSNRindBButton = uibutton(app.UIFigure, 'push');
            app.CalculatePSNRindBButton.ButtonPushedFcn = createCallbackFcn(app, @CalculatePSNRindBButtonPushed, true);
            app.CalculatePSNRindBButton.Text = 'Calculate PSNR in dB';
            app.psnr_value = uieditfield(app.UIFigure, 'text');
            app.Message_Text = uieditfield(app.UIFigure, 'text');
            app.confidentiality = uieditfield(app.UIFigure, 'text');
            app.TextCheckBox = uicheckbox(app.UIFigure);
            app.TextCheckBox.ValueChangedFcn = createCallbackFcn(app, @TextCheckBoxValueChanged, true);
            app.TextCheckBox.Text = 'Text';
            app.ImageCheckBox = uicheckbox(app.UIFigure);
            app.ImageCheckBox.ValueChangedFcn = createCallbackFcn(app, @ImageCheckBoxValueChanged, true);
            app.ImageCheckBox.Text = 'Image';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = keypairgenerator

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end